classdef ni_daq_KSU_v1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        VoltsCheckBox       matlab.ui.control.CheckBox
        MsgLabel            matlab.ui.control.Label
        SavePanel           matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        pushbutton_save     matlab.ui.control.Button
        edit_savefilename   matlab.ui.control.EditField
        text14              matlab.ui.control.Label
        text17              matlab.ui.control.Label
        uipanel7            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        FFTButton           matlab.ui.control.Button
        PlotButton          matlab.ui.control.Button
        popupmenu_plot      matlab.ui.control.DropDown
        text16              matlab.ui.control.Label
        text15              matlab.ui.control.Label
        popupmenu_plotfft   matlab.ui.control.DropDown
        text13              matlab.ui.control.Label
        pushbutton_set      matlab.ui.control.Button
        uipanel6            matlab.ui.container.Panel
        GridLayout9         matlab.ui.container.GridLayout
        pushbutton_connect  matlab.ui.control.Button
        edit_devname        matlab.ui.control.EditField
        text12              matlab.ui.control.Label
        uipanel5            matlab.ui.container.Panel
        GridLayout8         matlab.ui.container.GridLayout
        edit_totalcount     matlab.ui.control.EditField
        edit_samplerate     matlab.ui.control.EditField
        text11              matlab.ui.control.Label
        text10              matlab.ui.control.Label
        uipanel4            matlab.ui.container.Panel
        GridLayout4         matlab.ui.container.GridLayout
        edit_sensitivity3   matlab.ui.control.EditField
        popupmenu_ai3       matlab.ui.control.DropDown
        text9               matlab.ui.control.Label
        text8               matlab.ui.control.Label
        uipanel3            matlab.ui.container.Panel
        GridLayout5         matlab.ui.container.GridLayout
        text7               matlab.ui.control.Label
        popupmenu_ai2       matlab.ui.control.DropDown
        edit_sensitivity2   matlab.ui.control.EditField
        text6               matlab.ui.control.Label
        uipanel2            matlab.ui.container.Panel
        GridLayout6         matlab.ui.container.GridLayout
        text5               matlab.ui.control.Label
        text4               matlab.ui.control.Label
        popupmenu_ai1       matlab.ui.control.DropDown
        edit_sensitivity1   matlab.ui.control.EditField
        uipanel1            matlab.ui.container.Panel
        GridLayout7         matlab.ui.container.GridLayout
        text3               matlab.ui.control.Label
        edit_sensitivity0   matlab.ui.control.EditField
        popupmenu_ai0       matlab.ui.control.DropDown
        text2               matlab.ui.control.Label
        pushbutton_record   matlab.ui.control.Button
        axes1               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function [f, p] = fft_analysis(app, t, data)
                % input: t : data (n x 1) [sec]
                %        x : data (n x 1)
                % output f: natural frequency
                %        p: amplitude spectrum
                % reference: https://www.mathworks.com/help/matlab/ref/fft.html
                fs = 1/diff(t(1:2)); %Sampling frequency
                N=length(data); %Length of signal
                ff1=abs(fft(data)); %Fourier Transform of A (accel.)
                P21=ff1/N; p=P21(1:N/2+1); % single-sided spectrum
                f=fs*(0:(N/2))/N;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ni_daq_KSU_v1_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to ni_daq_KSU_v1 (see VARARGIN)
%             
%             % Choose default command line output for ni_daq_KSU_v1
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: popupmenu_plot
        function popupmenu_plot_Callback(app, event)
            % hObject    handle to popupmenu_plot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_plot contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_plot
            global data t
            v = app.popupmenu_plot.Value;
            col = find(strcmp(app.popupmenu_plot.Items, v));
            if and(col > size(data,2), col < 5)
                return;
            end
            figure;
            if col == 5
                for i = 1:size(data,2)
                    plot(t, data(:,i)); hold on;
                end
            else
                plot(t, data(:,col));
            end
            legend('show')
            xlabel('Time(s)');  ylabel('Acceleration');
            title(v);
        end

        % Value changed function: popupmenu_plotfft
        function popupmenu_plotfft_Callback(app, event)
            % hObject    handle to popupmenu_plotfft (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_plotfft contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_plotfft
            global data t
            
            contents = app.popupmenu_plotfft.Value;
            col = find(strcmp(app.popupmenu_plotfft.Items, contents));
            if and(col > size(data,2), col < 5)
                return;
            end
            
            [f, p] = fft_analysis(app, t, data(:,col));% fft for analysis
            [p_max, index]= max(p); % find the peak frequency index
            f_nat = f(index) % get the peak frequency
            
            figure
            plot(f,p,'k'); hold on;
            plot(f_nat, p_max,'pr','MarkerFaceColor','r')
            xlabel('Frequency(Hz)');  ylabel('|P(f)|');
            title(['Amplitude Spectrum of A(t) in ', contents]);
            xlim([0,20])
            
            app.MsgLabel.Text = ['Peak freq of ', contents, ' = ', num2str(f_nat), ' Hz'];
        end

        % Button pushed function: pushbutton_connect
        function pushbutton_connect_Callback(app, event)
            % hObject    handle to pushbutton_connect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global d s data t
            d=daq.getDevices
            if ~isempty(d)
                s=daq.createSession('ni');
                set(app.pushbutton_connect,'Enable','off');
                set(app.pushbutton_set,'Enable','on');
                app.MsgLabel.Text = {d.Description, d.ID};
                app.edit_devname.Value = d.ID;
            end
        end

        % Button pushed function: pushbutton_record
        function pushbutton_record_Callback(app, event)
            % hObject    handle to pushbutton_record (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global d s data t
            data=startForeground(s);
            cla(app.axes1);
            for i = 1:size(data,2)
                plot(app.axes1, t, data(:,i)); hold(app.axes1, "on");
            end
            legend(app.axes1, 'show')
            set(app.pushbutton_save,'Enable','on');
            app.MsgLabel.Text = {['Will run for ', num2str(s.NumberOfScans),' scans (', num2str(s.DurationInSeconds),' seconds) at ', num2str(s.Rate),' scans/second'],
                 ['Data Saved']};
        end

        % Button pushed function: pushbutton_save
        function pushbutton_save_Callback(app, event)
            % hObject    handle to pushbutton_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global data t
            save(app.edit_savefilename.Value,'t','data');
        end

        % Button pushed function: pushbutton_set
        function pushbutton_set_Callback(app, event)
            % hObject    handle to pushbutton_set (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global d s data t
            devname = app.edit_devname.Value;
            
            for i = 1:length(s.Channels) % delete all channel
                s.removeChannel(1);
            end
            s.Rate = str2double(app.edit_samplerate.Value);
            s.NumberOfScans=str2double(app.edit_totalcount.Value);
            t = (0.0:1.0:(str2double(app.edit_totalcount.Value)-1))'./s.Rate;
            flag = app.VoltsCheckBox.Value;
            
            if flag
                if ~strcmp(app.popupmenu_ai0.Value, 'N/A')
                    s.addAnalogInputChannel(devname,app.popupmenu_ai0.Value,'Voltage');
                    set(app.edit_sensitivity0,'Value', '1'); 
                    set(app.pushbutton_record,'Enable','on');
                end
    
                if ~strcmp(app.popupmenu_ai1.Value, 'N/A')
                    s.addAnalogInputChannel(devname,app.popupmenu_ai1.Value,'Voltage');
                    set(app.edit_sensitivity1,'Value', '1'); 
                    set(app.pushbutton_record,'Enable','on');
                end
    
                if ~strcmp(app.popupmenu_ai2.Value, 'N/A')
                    s.addAnalogInputChannel(devname,app.popupmenu_ai2.Value,'Voltage');
                    set(app.edit_sensitivity2,'Value', '1'); 
                    set(app.pushbutton_record,'Enable','on');
                end
                
                if ~strcmp(app.popupmenu_ai3.Value, 'N/A')
                    s.addAnalogInputChannel(devname,app.popupmenu_ai3.Value,'Voltage');
                    set(app.edit_sensitivity3,'Value', '1'); 
                    set(app.pushbutton_record,'Enable','on');
                end               
            else
                if ~strcmp(app.popupmenu_ai0.Value, 'N/A')
                    s.addAnalogInputChannel(devname,app.popupmenu_ai0.Value,'Accelerometer');
                    s.Channels(1).Sensitivity=str2double(app.edit_sensitivity0.Value);
                    set(app.pushbutton_record,'Enable','on');
                end
    
                if ~strcmp(app.popupmenu_ai1.Value, 'N/A')
                    s.addAnalogInputChannel(devname,app.popupmenu_ai1.Value,'Accelerometer');
                    s.Channels(2).Sensitivity=str2double(app.edit_sensitivity1.Value);
                    set(app.pushbutton_record,'Enable','on');
                end
    
                if ~strcmp(app.popupmenu_ai2.Value, 'N/A')
                    s.addAnalogInputChannel(devname,app.popupmenu_ai2.Value,'Accelerometer');
                    s.Channels(3).Sensitivity=str2double(app.edit_sensitivity2.Value);
                    set(app.pushbutton_record,'Enable','on');
                end
                
                if ~strcmp(app.popupmenu_ai3.Value, 'N/A')
                    s.addAnalogInputChannel(devname,app.popupmenu_ai3.Value,'Accelerometer');
                    s.Channels(4).Sensitivity=str2double(app.edit_sensitivity3.Value);
                    set(app.pushbutton_record,'Enable','on');
                end                
            end
            app.MsgLabel.Text = ['Will run for ', num2str(s.NumberOfScans),' scans (', num2str(s.DurationInSeconds),' seconds) at ', num2str(s.Rate),' scans/second'];
            s
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            popupmenu_plot_Callback(app, event);
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            popupmenu_plotfft_Callback(app, event);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ni_daq_KSU_v1';
            app.GridLayout = uigridlayout(app.figure1);
            app.axes1 = uiaxes(app.GridLayout);
            app.pushbutton_record = uibutton(app.GridLayout, 'push');
            app.pushbutton_record.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_record_Callback, true);
            app.pushbutton_record.Enable = 'off';
            app.pushbutton_record.Text = 'Record';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Signal 0';
            app.GridLayout7 = uigridlayout(app.uipanel1);
            app.text2 = uilabel(app.GridLayout7);
            app.text2.Text = 'Channel';
            app.popupmenu_ai0 = uidropdown(app.GridLayout7);
            app.popupmenu_ai0.Items = {'N/A', 'ai0', 'ai1', 'ai2', 'ai3'};
            app.popupmenu_ai0.Value = 'N/A';
            app.edit_sensitivity0 = uieditfield(app.GridLayout7, 'text');
            app.edit_sensitivity0.Value = '0.01';
            app.text3 = uilabel(app.GridLayout7);
            app.text3.Text = 'Sensitivity (V/g)';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Title = 'Signal 1';
            app.GridLayout6 = uigridlayout(app.uipanel2);
            app.edit_sensitivity1 = uieditfield(app.GridLayout6, 'text');
            app.edit_sensitivity1.Value = '0.01';
            app.popupmenu_ai1 = uidropdown(app.GridLayout6);
            app.popupmenu_ai1.Items = {'N/A', 'ai0', 'ai1', 'ai2', 'ai3'};
            app.popupmenu_ai1.Value = 'N/A';
            app.text4 = uilabel(app.GridLayout6);
            app.text4.Text = 'Channel';
            app.text5 = uilabel(app.GridLayout6);
            app.text5.Text = 'Sensitivity (V/g)';
            app.uipanel3 = uipanel(app.GridLayout);
            app.uipanel3.Title = 'Signal 2';
            app.GridLayout5 = uigridlayout(app.uipanel3);
            app.text6 = uilabel(app.GridLayout5);
            app.text6.Text = 'Sensitivity (V/g)';
            app.edit_sensitivity2 = uieditfield(app.GridLayout5, 'text');
            app.edit_sensitivity2.Value = '0.01';
            app.popupmenu_ai2 = uidropdown(app.GridLayout5);
            app.popupmenu_ai2.Items = {'N/A', 'ai0', 'ai1', 'ai2', 'ai3'};
            app.popupmenu_ai2.Value = 'N/A';
            app.text7 = uilabel(app.GridLayout5);
            app.text7.Text = 'Channel';
            app.uipanel4 = uipanel(app.GridLayout);
            app.uipanel4.Title = 'Signal 3';
            app.GridLayout4 = uigridlayout(app.uipanel4);
            app.text8 = uilabel(app.GridLayout4);
            app.text8.Text = 'Sensitivity (V/g)';
            app.text9 = uilabel(app.GridLayout4);
            app.text9.Text = 'Channel';
            app.popupmenu_ai3 = uidropdown(app.GridLayout4);
            app.popupmenu_ai3.Items = {'N/A', 'ai0', 'ai1', 'ai2', 'ai3'};
            app.popupmenu_ai3.Value = 'N/A';
            app.edit_sensitivity3 = uieditfield(app.GridLayout4, 'text');
            app.edit_sensitivity3.Value = '0.01';
            app.uipanel5 = uipanel(app.GridLayout);
            app.uipanel5.Title = 'Time Setting';
            app.GridLayout8 = uigridlayout(app.uipanel5);
            app.text10 = uilabel(app.GridLayout8);
            app.text10.Text = 'Sample Count';
            app.text11 = uilabel(app.GridLayout8);
            app.text11.Text = 'Freq (Hz)';
            app.edit_samplerate = uieditfield(app.GridLayout8, 'text');
            app.edit_samplerate.Value = '1000';
            app.edit_totalcount = uieditfield(app.GridLayout8, 'text');
            app.edit_totalcount.Value = '5000';
            app.uipanel6 = uipanel(app.GridLayout);
            app.uipanel6.Title = 'Device';
            app.GridLayout9 = uigridlayout(app.uipanel6);
            app.text12 = uilabel(app.GridLayout9);
            app.text12.Text = 'Device Name';
            app.edit_devname = uieditfield(app.GridLayout9, 'text');
            app.edit_devname.Value = 'dev3';
            app.pushbutton_connect = uibutton(app.GridLayout9, 'push');
            app.pushbutton_connect.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_connect_Callback, true);
            app.pushbutton_connect.Text = 'connect';
            app.pushbutton_set = uibutton(app.GridLayout, 'push');
            app.pushbutton_set.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_set_Callback, true);
            app.pushbutton_set.Enable = 'off';
            app.pushbutton_set.Text = 'set';
            app.text13 = uilabel(app.GridLayout);
            app.text13.Text = 'ME4501 DAQ in MATLAB';
            app.uipanel7 = uipanel(app.GridLayout);
            app.uipanel7.Title = 'Figure';
            app.GridLayout3 = uigridlayout(app.uipanel7);
            app.popupmenu_plotfft = uidropdown(app.GridLayout3);
            app.popupmenu_plotfft.Items = {'Signal 0', 'Signal 1', 'Signal 2', 'Signal 3'};
            app.popupmenu_plotfft.ValueChangedFcn = createCallbackFcn(app, @popupmenu_plotfft_Callback, true);
            app.popupmenu_plotfft.Value = 'Signal 0';
            app.text15 = uilabel(app.GridLayout3);
            app.text15.Text = 'plot data';
            app.text16 = uilabel(app.GridLayout3);
            app.text16.Text = 'plot (fft)';
            app.popupmenu_plot = uidropdown(app.GridLayout3);
            app.popupmenu_plot.Items = {'Signal 0', 'Signal 1', 'Signal 2', 'Signal 3', 'All'};
            app.popupmenu_plot.ValueChangedFcn = createCallbackFcn(app, @popupmenu_plot_Callback, true);
            app.popupmenu_plot.Value = 'Signal 0';
            app.PlotButton = uibutton(app.GridLayout3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FFTButton = uibutton(app.GridLayout3, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.text17 = uilabel(app.GridLayout);
            app.text17.Text = 'dkim97@kennesaw.edu';
            app.SavePanel = uipanel(app.GridLayout);
            app.SavePanel.Title = 'Save';
            app.GridLayout2 = uigridlayout(app.SavePanel);
            app.text14 = uilabel(app.GridLayout2);
            app.text14.Text = 'File Name';
            app.edit_savefilename = uieditfield(app.GridLayout2, 'text');
            app.edit_savefilename.Value = 'yourfilename';
            app.pushbutton_save = uibutton(app.GridLayout2, 'push');
            app.pushbutton_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_save_Callback, true);
            app.pushbutton_save.Enable = 'off';
            app.pushbutton_save.Text = 'save';
            app.MsgLabel = uilabel(app.GridLayout);
            app.MsgLabel.Text = {'Msg'; ''};
            app.VoltsCheckBox = uicheckbox(app.GridLayout);
            app.VoltsCheckBox.Text = 'Volts';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ni_daq_KSU_v1_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ni_daq_KSU_v1_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end